classdef chlamy_CDS_optimize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DNAbasesupstreamfromtargetCDSLabel  matlab.ui.control.Label
        Upstream40                    matlab.ui.control.EditField
        TargetaminoacidsequenceLabel  matlab.ui.control.Label
        Target_AA                     matlab.ui.control.EditField
        OptimizedCodingSequenceLabel  matlab.ui.control.Label
        Output_seq                    matlab.ui.control.EditField
        Go                            matlab.ui.control.Button
        ElapsedtimeLabel              matlab.ui.control.Label
        Time                          matlab.ui.control.NumericEditField
        FEaroundATGLabel              matlab.ui.control.Label
        FE                            matlab.ui.control.NumericEditField
        ProgressreportLabel           matlab.ui.control.Label
        Progress                      matlab.ui.control.EditField
        PathLabel                     matlab.ui.control.Label
        Path                          matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        OutputfilenameLabel           matlab.ui.control.Label
        Fname                         matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: Go
        function GoPushed(app, event)
            
            % set initial updates
            app.Lamp.Color = 'y';
            app.Path.Value = pwd;
            app.Progress.Value = 'started running';
            tic
            
            % organize input parameters
            load('ref_CDSs.mat')
            app.Progress.Value = 'loaded references';
            
            aa_seq = app.Target_AA.Value;
            fourty_nt_upstream_atg = app.Upstream40.Value;
            output_file_name = [app.Fname.Value '.fa'];
            gene_title = 'optimized_gene';
            
            % tun optimization pipeline
            app.Progress.Value = 'starting optimization pipeline';
            [optimized_dna_seq, best_mean_FE] = chlamy_optimize_coding_seq_IW(aa_seq, ...
                fourty_nt_upstream_atg, ref_CDSs, output_file_name, gene_title);
            
            % finalize and give output
            app.Progress.Value = 'finished optimization';
            app.Lamp.Color = 'b';
            app.Output_seq.Value = optimized_dna_seq;
            app.FE.Value = best_mean_FE;
            Elp_T = toc;
            app.Time.Value = Elp_T;
            % make sound :)
            load handel;
            sound(y,Fs);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DNAbasesupstreamfromtargetCDSLabel = uilabel(app.UIFigure);
            app.DNAbasesupstreamfromtargetCDSLabel.Text = '40 DNA bases upstream from target CDS';
            app.Upstream40 = uieditfield(app.UIFigure, 'text');
            app.TargetaminoacidsequenceLabel = uilabel(app.UIFigure);
            app.TargetaminoacidsequenceLabel.Text = 'Target amino acid sequence';
            app.Target_AA = uieditfield(app.UIFigure, 'text');
            app.OptimizedCodingSequenceLabel = uilabel(app.UIFigure);
            app.OptimizedCodingSequenceLabel.Text = 'Optimized Coding Sequence';
            app.Output_seq = uieditfield(app.UIFigure, 'text');
            app.Go = uibutton(app.UIFigure, 'push');
            app.Go.ButtonPushedFcn = createCallbackFcn(app, @GoPushed, true);
            app.Go.Text = 'Optimize my gene';
            app.ElapsedtimeLabel = uilabel(app.UIFigure);
            app.ElapsedtimeLabel.Text = 'Elapsed time';
            app.Time = uieditfield(app.UIFigure, 'numeric');
            app.FEaroundATGLabel = uilabel(app.UIFigure);
            app.FEaroundATGLabel.Text = 'FE around ATG';
            app.FE = uieditfield(app.UIFigure, 'numeric');
            app.ProgressreportLabel = uilabel(app.UIFigure);
            app.ProgressreportLabel.Text = 'Progress report';
            app.Progress = uieditfield(app.UIFigure, 'text');
            app.PathLabel = uilabel(app.UIFigure);
            app.PathLabel.Text = 'Path';
            app.Path = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.OutputfilenameLabel = uilabel(app.UIFigure);
            app.OutputfilenameLabel.Text = 'Output file name';
            app.Fname = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = chlamy_CDS_optimize

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end